classdef JMC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure           % UI Figure
        LabelTextArea matlab.ui.control.Label    % 阻带上截止频率Hz
        TextArea      matlab.ui.control.TextArea
        Label         matlab.ui.control.Label    % 通带上截止频率Hz
        TextArea2     matlab.ui.control.TextArea
        Button        matlab.ui.control.Button   % 加噪声
        Button2       matlab.ui.control.Button   % 滤波
        UIAxes        matlab.ui.control.UIAxes   % 原始信号波形
        UIAxes2       matlab.ui.control.UIAxes   % 原始信号幅度谱
        Label2        matlab.ui.control.Label    % 通带下截止频率Hz
        TextArea3     matlab.ui.control.TextArea
        Label3        matlab.ui.control.Label    % 阻带下截止频率Hz
        TextArea4     matlab.ui.control.TextArea
        UIAxes3       matlab.ui.control.UIAxes   % 加噪信号波形
        UIAxes4       matlab.ui.control.UIAxes   % 加噪信号幅度谱
        UIAxes5       matlab.ui.control.UIAxes   % 滤波后信号波形
        UIAxes6       matlab.ui.control.UIAxes   % 信号频谱
        Label4        matlab.ui.control.Label    % 信噪比dB
        TextArea5     matlab.ui.control.TextArea
        Button4       matlab.ui.control.Button   % 播放
        Button5       matlab.ui.control.Button   % 播放
        Button6       matlab.ui.control.Button   % 播放
        Label5        matlab.ui.control.Label    % 阻带最小增益dB
        TextArea6     matlab.ui.control.TextArea
        Label6        matlab.ui.control.Label    % 音频信息
        TextArea7     matlab.ui.control.TextArea
        Label7        matlab.ui.control.Label    % 阻带上和通带上截止频率设为0为LPF
        Label8        matlab.ui.control.Label    % 阻带下和通带下设为信号最高频率Fs/2为HPF
        Label9        matlab.ui.control.Label    % 输入频率满足阻带上、通带上、通带下、通带上 依次...
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global x;
            global Fs;
            global N;
            global df;
            global f;
            [filename,pathname] = ( uigetfile({'*.wav';'*.mp3'}) );
            str=[pathname filename];
            [x,Fs] = audioread(str);
            N = length(x);
            plot(app.UIAxes,x);

            y=fft(x,N);
            df = Fs/N;
            f = 0:df:df*(round(N/2)-1);
            Xk=abs(y)/N;
            Xk(1) = Xk(1)*2;
            plot(app.UIAxes2,f,Xk(1:round(N/2)));
            
            
            app.TextArea7.Value = {['N = ',num2str(N)];['Fs = ',num2str(Fs),' Hz'];['Fs/2 = ',num2str(Fs/2),' Hz']};
        end

        % TextArea value changed function
        function TextAreaValueChanged(app)
            global fs1;
            fs1= app.TextArea.Value;
            fs1 = eval(char(fs1));
            % whos fun

            
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            global x;
            global Fs;
            global N;
            global df;
            global f;
            global SNR;
            global x_awgn;
            
            x_awgn = awgn(x,SNR);

            plot(app.UIAxes3,x_awgn);
            y_awgn = fft(x_awgn,N);
            Xk_awgn=abs(y_awgn)/N;
            Xk_awgn(1) = 2*Xk_awgn(1);
            plot(app.UIAxes4,f,Xk_awgn(1:round(N/2)));
            
      
%             hold(app.UIAxes,'on')
% 
%             plot(app.UIAxes,judge);
%             hold(app.UIAxes,'off');
% 
%             plot(app.UIAxes2,voice_h);
%             hold(app.UIAxes2,'on')
% 
%             plot(app.UIAxes2,judge);
%             hold(app.UIAxes2,'off');
% 
%             app.TextArea2.Value = {char(fun_dec)';['BER = ',num2str(BER)]};

        end

        % ButtonGroup selection change function
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
        end

        % Button2 button pushed function
        function Button2ButtonPushed(app)
            global x_awgn;
            global Fs;
            global N;
            global f;
            global fs1;
            global fs2;
            global fp1;
            global fp2;
            global alphaS;
            global x_denoise_BPF;
            
            ws1 = 2*pi*fs1/Fs                     % 阻带截止频率
            wp1 = 2*pi*fp1/Fs                     % 通带截止频率
            wp2 = 2*pi*fp2/Fs                     % 通带截止频率
            ws2 = 2*pi*fs2/Fs                     % 阻带截止频率
            alphaS
            
            hn_BPF = fir_filter(ws1,wp1,wp2,ws2,alphaS);
            x_denoise_BPF = filter(hn_BPF,1,x_awgn);


            plot(app.UIAxes5,x_denoise_BPF);
            
            y_denoise_BPF = fft(x_denoise_BPF,N);
            Xk_denoise_BPF=abs(y_denoise_BPF)/N;
            Xk_denoise_BPF(1) = 2*Xk_denoise_BPF(1);
            plot(app.UIAxes6,f,Xk_denoise_BPF(1:round(N/2)));


        end

        % Button3 button pushed function
        function openwav(app)
%             global x;
%             global Fs;
%             global N;
%             global df;
%             global f;
%             [filename,pathname] = ( uigetfile({'*.wav';'*.mp3'}) );
%             str=[pathname filename];
%             [x,Fs] = audioread(str);
%             N = length(x);
%             plot(app.UIAxes,x);
% 
%             y=fft(x,N);
%             df = Fs/N;
%             f = 0:df:df*(round(N/2)-1);
%             Xk=abs(y)/N;
%             Xk(1) = Xk(1)*2;
%             plot(app.UIAxes2,f,Xk(1:round(N/2)));
%             
%             
%             app.TextArea7.Value = {['N = ',num2str(N)];['Fs = ',num2str(Fs),' Hz']};
            
        end

        % TextArea5 value changed function
        function TextArea5ValueChanged(app)
            global SNR;
            SNR = app.TextArea5.Value;
            SNR = eval(char(SNR));
            % whos SNR
        end

        % TextArea2 value changed function
        function wp1_value(app)
            global fp1;
            fp1 = app.TextArea2.Value;
            fp1 = eval(char(fp1));
            
        end

        % TextArea4 value changed function
        function ws2_value(app)
            global fs2; 
            fs2 = app.TextArea4.Value;
            fs2 = eval(char(fs2));
            
        end

        % TextArea3 value changed function
        function wp2_value(app)
            global fp2;
            fp2 = app.TextArea3.Value;
            fp2 = eval(char(fp2));
            
        end

        % TextArea6 value changed function
        function alphaS_value(app)
            global alphaS;
            alphaS = app.TextArea6.Value;
            alphaS = eval(char(alphaS));
            
        end

        % EditField value changed function
        function wav_info(app)

        end

        % TextArea7 value changed function
        function wav_info_new(app)
            value = app.TextArea7.Value;
            
        end

        % Button4 button pushed function
        function sound_original(app)
            global x;
            global Fs;
            sound(x,Fs);
        end

        % Button5 button pushed function
        function sound_awgn(app)
            global x_awgn;
            global Fs;
            sound(x_awgn,Fs);
        end

        % Button6 button pushed function
        function sound_BPF(app)
            global x_denoise_BPF;
            global Fs;
            sound(x_denoise_BPF,Fs);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = '阻带上截止频率Hz';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '通带上截止频率Hz';
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.ValueChangedFcn = createCallbackFcn(app, @wp1_value);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = '加噪声';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed);
            app.Button2.Text = '滤波';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始信号波形');
            xlabel(app.UIAxes, 't');
            ylabel(app.UIAxes, '幅度值');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '原始信号幅度谱');
            xlabel(app.UIAxes2, '频率(单位：Hz)');
            ylabel(app.UIAxes2, '幅度值');
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = '通带下截止频率Hz';
            app.TextArea3 = uitextarea(app.UIFigure);
            app.TextArea3.ValueChangedFcn = createCallbackFcn(app, @wp2_value);
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = '阻带下截止频率Hz';
            app.TextArea4 = uitextarea(app.UIFigure);
            app.TextArea4.ValueChangedFcn = createCallbackFcn(app, @ws2_value);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '加噪信号波形');
            xlabel(app.UIAxes3, 't');
            ylabel(app.UIAxes3, '幅度值');
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, '加噪信号幅度谱');
            xlabel(app.UIAxes4, '频率(单位：Hz)');
            ylabel(app.UIAxes4, '幅度值');
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, '滤波后信号波形');
            xlabel(app.UIAxes5, 't');
            ylabel(app.UIAxes5, '幅度值');
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, '信号频谱');
            xlabel(app.UIAxes6, '频率(单位：Hz)');
            ylabel(app.UIAxes6, '幅度值');
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = '信噪比dB';
            app.TextArea5 = uitextarea(app.UIFigure);
            app.TextArea5.ValueChangedFcn = createCallbackFcn(app, @TextArea5ValueChanged);
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @sound_original);
            app.Button4.Text = '播放';
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @sound_awgn);
            app.Button5.Text = '播放';
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @sound_BPF);
            app.Button6.Text = '播放';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = '阻带最小增益dB';
            app.TextArea6 = uitextarea(app.UIFigure);
            app.TextArea6.ValueChangedFcn = createCallbackFcn(app, @alphaS_value);
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = '音频信息';
            app.TextArea7 = uitextarea(app.UIFigure);
            app.TextArea7.ValueChangedFcn = createCallbackFcn(app, @wav_info_new);
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = '阻带上和通带上截止频率设为0为LPF';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = '阻带下和通带下设为信号最高频率Fs/2为HPF';
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = '输入频率满足阻带上、通带上、通带下、通带上 依次 不递减';
        end
    end

    methods (Access = public)

        % Construct app
        function app = JMC()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end